classdef VNSBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % Amplitude
        Amplitude              matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Pulse W...
        PulseWidth             matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Frequency
        Frequency              matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Duration
        Duration               matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.syn = SynapseAPI('localhost');
            app.joy = JoyInit(id);
        end

        % Amplitude value changed function
        function AmplitudeValueChanged(app)
            value = app.Amplitude.Value;
            app.syn.setParameterValue('Stim1', 'Amp', value)
        end

        % PulseWidth value changed function
        function PulseWidthValueChanged(app)
            value = app.PulseWidth.Value;
            app.syn.setParameterValue('Stim1', 'PulseWidth', value)            
        end

        % Frequency value changed function
        function FrequencyValueChanged(app)
            value = app.Frequency.Value;
            app.syn.setParameterValue('Stim1', 'Frequency', value)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.UIFigure, 'numeric');
            app.Amplitude.ValueChangedFcn = createCallbackFcn(app, @AmplitudeValueChanged);
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Pulse Width';
            app.PulseWidth = uieditfield(app.UIFigure, 'numeric');
            app.PulseWidth.ValueChangedFcn = createCallbackFcn(app, @PulseWidthValueChanged);
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Frequency';
            app.Frequency = uieditfield(app.UIFigure, 'numeric');
            app.Frequency.ValueChangedFcn = createCallbackFcn(app, @FrequencyValueChanged);
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Duration';
            app.Duration = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = VNSBox()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end