classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureNameMe                matlab.ui.Figure
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        TaskDropDownLabel             matlab.ui.control.Label
        TaskDropDown                  matlab.ui.control.DropDown
        LoadWindowButton              matlab.ui.control.Button
        CloseWindowButton             matlab.ui.control.Button
        TestingLogitechCheckBox       matlab.ui.control.CheckBox
        ControllerPanel               matlab.ui.container.Panel
        DeviceDropDownLabel           matlab.ui.control.Label
        DeviceDropDown                matlab.ui.control.DropDown
        TabGroup                      matlab.ui.container.TabGroup
        JoystickTab                   matlab.ui.container.Tab
        JoystickButton                matlab.ui.control.Button
        ConnectedLampLabel            matlab.ui.control.Label
        JoystickConnectedLamp         matlab.ui.control.Lamp
        VelocityEditFieldLabel        matlab.ui.control.Label
        VelocityEditField             matlab.ui.control.NumericEditField
        MouseTab                      matlab.ui.container.Tab
        VelocityEditField_2Label      matlab.ui.control.Label
        VelocityEditField_2           matlab.ui.control.NumericEditField
        TaskSettingsPanel             matlab.ui.container.Panel
        DurationEditFieldLabel        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        ITIEditFieldLabel             matlab.ui.control.Label
        ITIEditField                  matlab.ui.control.NumericEditField
        ResetEditFieldLabel           matlab.ui.control.Label
        ResetEditField                matlab.ui.control.NumericEditField
        CursorSizeEditFieldLabel      matlab.ui.control.Label
        CursorSizeEditField           matlab.ui.control.NumericEditField
        TargetSizeEditFieldLabel      matlab.ui.control.Label
        TargetSizeEditField           matlab.ui.control.NumericEditField
        DisplayLabel                  matlab.ui.control.Label
        TimingLabel                   matlab.ui.control.Label
        GazeSizeEditFieldLabel        matlab.ui.control.Label
        GazeSizeEditField             matlab.ui.control.NumericEditField
        RewardEditFieldLabel          matlab.ui.control.Label
        RewardEditField               matlab.ui.control.NumericEditField
        ConfigurationPanel            matlab.ui.container.Panel
        DonotappendtimestampCheckBox  matlab.ui.control.CheckBox
        SubjectEditFieldLabel         matlab.ui.control.Label
        SubjectEditField              matlab.ui.control.EditField
        DataDirectoryEditFieldLabel   matlab.ui.control.Label
        DataDirectoryEditField        matlab.ui.control.EditField
        SuffixEditFieldLabel          matlab.ui.control.Label
        SuffixEditField               matlab.ui.control.EditField
        DataFileEditFieldLabel        matlab.ui.control.Label
        DataFileEditField             matlab.ui.control.EditField
        DispsubstringButton           matlab.ui.control.Button
        TrialNumEditFieldLabel        matlab.ui.control.Label
        TrialNumEditField             matlab.ui.control.NumericEditField
        EyelinkEDFEditFieldLabel      matlab.ui.control.Label
        EyelinkEDFEditField           matlab.ui.control.EditField
        WindowedCheckBox              matlab.ui.control.CheckBox
        EyelinkCheckBox               matlab.ui.control.CheckBox
        SimulationCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        win % master window handle
        winRect % master window size
        win2 % slave window handle
        winRect2 % slave window size
        joy % joystick handle
        taskName
        taskID % numerical identifier
        loopTask % logical
        subName
        fileName % subject data
        filePath % subject path
        fullPath % filePath+fileName+timestamp/suffix
        trialNum % initial trial number
    end
    
    methods (Access = private)
        
        function UpdateTask(app)
            StopButtonPushed(app);
            StartButtonPushed(app);
            %app.taskName = app.TaskDropDown.Value;
            %app.CursorSizeEditField.Value
        end
        
        function saveStr = SaveData(app)
            if isempty(app.SubjectEditField.Value)
                app.subName = strcat('sub',num2str(randi([1000,4000])));
            else
                app.subName = app.SubjectEditField.Value;
            end
            
            if app.DonotappendtimestampCheckBox.Value
                fullSuf = app.SuffixEditField.Value;
            else
                dateSuf = datestr(now,'yyyy-mm-dd');
                fullSuf = strcat(app.SuffixEditField.Value, '_', dateSuf);
            end
            if isempty(app.DataFileEditField.Value)
                fullFileName = strcat(app.subName, '_', fullSuf);
            else
                fullFileName = strcat(app.DataFileEditField.Value, '_', fullSuf);
            end
            app.fileName = strcat(fullFileName, '.mat');
            
            if isempty(app.DataDirectoryEditField.Value)
                app.filePath = pwd;
            else
                app.filePath = app.DataDirectoryEditField.Value;
            end
            saveStr = fullfile(app.filePath,app.fileName);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.taskName = 'Center';
            %app.taskID = 0;
            global dat;
            app.TaskDropDown.Value = 'Center';
            if isunix
                app.WindowedCheckBox.Value = 1;
            end
            
            %app.targetRad = 2;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global dat;
            if isempty(app.trialNum)
                app.trialNum = 1;
                app.TrialNumEditField.Value = app.trialNum;
            end            
            app.loopTask = 1;
            while app.loopTask
                if strcmp(app.TaskDropDown.Value, 'Task3')
                    DoTrial2(app);
                else
                    DoTrial(app);
                end
                if app.ITIEditField.Value == 0
                    pause(0.01); % pause task to give time to interrupt with stop
                else
                    pause(app.ITIEditField.Value);
                end
                app.TrialNumEditField.Value = app.trialNum;
            end
        end

        % Button pushed function: LoadWindowButton
        function LoadWindowButtonPushed(app, event)
            [app.win, app.winRect,~,~,app.win2,app.winRect2] = WinInit(app.WindowedCheckBox.Value);
            if strcmp(app.DeviceDropDown.Value,'Joystick')
                app.joy = JoyInit(1);
            end
        end

        % Button pushed function: CloseWindowButton
        function CloseWindowButtonPushed(app, event)
            WinClose(app);
            close(app.joy);
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            app.taskName = app.TaskDropDown.Value;
            %{
            switch app.taskName
                case 'Center'
                    app.taskID = 0;
                case 'XY'
                    app.taskID = 1;
            end
            %}
        end

        % Button pushed function: JoystickButton
        function JoystickButtonPushed(app, event)
            app.joy = JoyInit(1);
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            %value = app.DeviceDropDown.Value;
            if strcmp(app.DeviceDropDown.Value, 'Joystick')
                app.JoystickConnectedLamp.Color = [0 1 0];
            else
                app.JoystickConnectedLamp.Color = [0.5 0.5 0.5];
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global dat;
            app.loopTask = 0;
            saveName = SaveData(app);
            if exist(saveName,'file')
                opt = '-append';
            else
                opt = '';
            end
            save(saveName, 'dat', opt);
            
        end

        % Value changed function: CursorSizeEditField
        function CursorSizeEditFieldValueChanged(app, event)
            value = app.CursorSizeEditField.Value;
            StopButtonPushed(app);
            %updateTask(app);
        end

        % Value changed function: TargetSizeEditField
        function TargetSizeEditFieldValueChanged(app, event)
            value = app.TargetSizeEditField.Value;
            StopButtonPushed(app);
            %updateTask(app);
        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            app.subName = app.SubjectEditField.Value;
        end

        % Button pushed function: DispsubstringButton
        function DispsubstringButtonPushed(app, event)
            str1 = SaveData(app);
            disp(str1);
        end

        % Callback function
        function TrialNumoldEditFieldValueChanged(app, event)
            app.trialNum = app.TrialNumoldEditField.Value;
            
        end

        % Value changed function: TrialNumEditField
        function TrialNumEditFieldValueChanged(app, event)
            app.trialNum = app.TrialNumEditField.Value;
            
        end

        % Value changed function: EyelinkCheckBox
        function EyelinkCheckBoxValueChanged(app, event)
            value = app.EyelinkCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureNameMe = uifigure;
            app.UIFigureNameMe.Name = 'UI Figure NameMe';
            app.StartButton = uibutton(app.UIFigureNameMe, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigureNameMe, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TaskDropDownLabel = uilabel(app.UIFigureNameMe);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.UIFigureNameMe);
            app.TaskDropDown.Items = {'Center', 'XY'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = 'Center';
            app.LoadWindowButton = uibutton(app.UIFigureNameMe, 'push');
            app.LoadWindowButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWindowButtonPushed, true);
            app.LoadWindowButton.Text = 'Load Window';
            app.CloseWindowButton = uibutton(app.UIFigureNameMe, 'push');
            app.CloseWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CloseWindowButtonPushed, true);
            app.CloseWindowButton.Text = 'Close Window';
            app.TestingLogitechCheckBox = uicheckbox(app.UIFigureNameMe);
            app.TestingLogitechCheckBox.Text = 'Testing (Logitech)';
            app.TestingLogitechCheckBox.Value = true;
            app.ControllerPanel = uipanel(app.UIFigureNameMe);
            app.ControllerPanel.Title = 'Controller';
            app.DeviceDropDownLabel = uilabel(app.ControllerPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ControllerPanel);
            app.DeviceDropDown.Items = {'Joystick', 'Mouse'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Joystick';
            app.TabGroup = uitabgroup(app.ControllerPanel);
            app.JoystickTab = uitab(app.TabGroup);
            app.JoystickTab.Title = 'Joystick';
            app.JoystickButton = uibutton(app.JoystickTab, 'push');
            app.JoystickButton.ButtonPushedFcn = createCallbackFcn(app, @JoystickButtonPushed, true);
            app.JoystickButton.Text = 'Joystick';
            app.ConnectedLampLabel = uilabel(app.JoystickTab);
            app.ConnectedLampLabel.Text = 'Connected';
            app.JoystickConnectedLamp = uilamp(app.JoystickTab);
            app.VelocityEditFieldLabel = uilabel(app.JoystickTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.JoystickTab, 'numeric');
            app.VelocityEditField.Value = 1;
            app.MouseTab = uitab(app.TabGroup);
            app.MouseTab.Title = 'Mouse';
            app.VelocityEditField_2Label = uilabel(app.MouseTab);
            app.VelocityEditField_2Label.Text = 'Velocity';
            app.VelocityEditField_2 = uieditfield(app.MouseTab, 'numeric');
            app.VelocityEditField_2.Value = 1;
            app.TaskSettingsPanel = uipanel(app.UIFigureNameMe);
            app.TaskSettingsPanel.Title = 'Task Settings';
            app.DurationEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.DurationEditField.Value = 4;
            app.ITIEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITIEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.ResetEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.ResetEditFieldLabel.Text = 'Reset';
            app.ResetEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.ResetEditField.Value = 1;
            app.CursorSizeEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.CursorSizeEditFieldLabel.Text = 'Cursor Size';
            app.CursorSizeEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.CursorSizeEditField.UpperLimitInclusive = 'off';
            app.CursorSizeEditField.Limits = [0 Inf];
            app.CursorSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CursorSizeEditFieldValueChanged, true);
            app.CursorSizeEditField.Value = 2;
            app.TargetSizeEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.TargetSizeEditFieldLabel.Text = 'Target Size';
            app.TargetSizeEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.TargetSizeEditField.UpperLimitInclusive = 'off';
            app.TargetSizeEditField.Limits = [0 Inf];
            app.TargetSizeEditField.ValueChangedFcn = createCallbackFcn(app, @TargetSizeEditFieldValueChanged, true);
            app.TargetSizeEditField.Value = 2;
            app.DisplayLabel = uilabel(app.TaskSettingsPanel);
            app.DisplayLabel.Text = 'Display';
            app.TimingLabel = uilabel(app.TaskSettingsPanel);
            app.TimingLabel.Text = 'Timing';
            app.GazeSizeEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.GazeSizeEditFieldLabel.Text = 'Gaze Size';
            app.GazeSizeEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.GazeSizeEditField.UpperLimitInclusive = 'off';
            app.GazeSizeEditField.Limits = [0 Inf];
            app.GazeSizeEditField.Value = 1;
            app.RewardEditFieldLabel = uilabel(app.TaskSettingsPanel);
            app.RewardEditFieldLabel.Text = 'Reward';
            app.RewardEditField = uieditfield(app.TaskSettingsPanel, 'numeric');
            app.ConfigurationPanel = uipanel(app.UIFigureNameMe);
            app.ConfigurationPanel.Title = 'Configuration';
            app.DonotappendtimestampCheckBox = uicheckbox(app.ConfigurationPanel);
            app.DonotappendtimestampCheckBox.Text = 'Do not append timestamp ';
            app.SubjectEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SubjectEditField.Value = 'Sub1';
            app.DataDirectoryEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.SuffixEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.SuffixEditFieldLabel.Text = 'Suffix';
            app.SuffixEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.DataFileEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.DispsubstringButton = uibutton(app.ConfigurationPanel, 'push');
            app.DispsubstringButton.ButtonPushedFcn = createCallbackFcn(app, @DispsubstringButtonPushed, true);
            app.DispsubstringButton.Text = 'Disp sub string';
            app.TrialNumEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.TrialNumEditFieldLabel.Text = 'Trial Num';
            app.TrialNumEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.TrialNumEditField.ValueChangedFcn = createCallbackFcn(app, @TrialNumEditFieldValueChanged, true);
            app.TrialNumEditField.Value = 1;
            app.EyelinkEDFEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.EyelinkEDFEditFieldLabel.Text = 'EyelinkEDF';
            app.EyelinkEDFEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.EyelinkEDFEditField.Value = 'demo.edf';
            app.WindowedCheckBox = uicheckbox(app.UIFigureNameMe);
            app.WindowedCheckBox.Text = 'Windowed';
            app.EyelinkCheckBox = uicheckbox(app.UIFigureNameMe);
            app.EyelinkCheckBox.ValueChangedFcn = createCallbackFcn(app, @EyelinkCheckBoxValueChanged, true);
            app.EyelinkCheckBox.Text = 'Eyelink';
            app.SimulationCheckBox = uicheckbox(app.UIFigureNameMe);
            app.SimulationCheckBox.Text = 'Simulation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureNameMe)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureNameMe)
        end
    end
end